classdef embedding_screen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StegoAppUIFigure             matlab.ui.Figure
        CopyrightLabel               matlab.ui.control.Label
        ImageStatusLamp              matlab.ui.control.Lamp
        ImageStatusLampLabel         matlab.ui.control.Label
        EnableUploadCheckBox         matlab.ui.control.CheckBox
        ResetButton                  matlab.ui.control.Button
        WritedownyoursecretmessageLabel  matlab.ui.control.Label
        SelectBitDropDown            matlab.ui.control.DropDown
        SelectBitDropDownLabel       matlab.ui.control.Label
        ChooseMethodDropDown         matlab.ui.control.DropDown
        ChooseMethodDropDownLabel    matlab.ui.control.Label
        MSEEditField                 matlab.ui.control.NumericEditField
        MSEEditFieldLabel            matlab.ui.control.Label
        PSNREditField                matlab.ui.control.NumericEditField
        PSNRLabel                    matlab.ui.control.Label
        FileNameEditField            matlab.ui.control.EditField
        UploadTextFileButton         matlab.ui.control.Button
        StegokeyEditField            matlab.ui.control.NumericEditField
        StegokeyEditFieldLabel       matlab.ui.control.Label
        SaveButton                   matlab.ui.control.Button
        EmbedButton                  matlab.ui.control.Button
        BackButton                   matlab.ui.control.Button
        ClearButton                  matlab.ui.control.Button
        StegoImageLabel              matlab.ui.control.Label
        OriginalImageLabel           matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        BrowseButton                 matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        SteganographyEmbeddingLabel  matlab.ui.control.Label
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Center GUI design
            clc;
            app.StegoAppUIFigure.Visible = 'off';
            movegui(app.StegoAppUIFigure,"center")
            app.StegoAppUIFigure.Visible = 'on';
            
            global image;
            global stego;
            global default_value;
            global text;
            global text_default;
            
            % Reset global variable (image)
            image=default_value;
            stego=default_value;
            text=default_value;
            text_default=default_value;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            clc;
            % Browse a file (image)
            global image;
            [filename, pathname]=uigetfile({'*.bmp';'*.png';'*.tif'},"open file");
            fullpathname = strcat(pathname,filename);
            try
                image = imread(fullpathname);
                imshow(image,"Parent",app.UIAxes);

                % keep window focused
                app.StegoAppUIFigure.Visible='on';
                app.StegoAppUIFigure.Visible='on';
            catch
                uiwait(warndlg('Please select an image'));
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            clc;
            screen1;
            closereq;
        end

        % Value changed function: ChooseMethodDropDown
        function ChooseMethodDropDownValueChanged(app, event)
            clc;
            % Enable or Disable Stegokey Field
            value = app.ChooseMethodDropDown.Value;
            if(strcmp(value, 'Random Selected LSB') || strcmp(value,'Random Several LSBs'))
                app.StegokeyEditField.Editable=true;
            else
                app.StegokeyEditField.Editable=false;
            end
        end

        % Button pushed function: EmbedButton
        function EmbedButtonPushed(app, event)
            clc;
            % waiting dialog 1
            fig = uifigure;
            d = uiprogressdlg(fig,'Title','Please Wait',...
                'Message','Opening the application');
            pause(.5)
            
            global image;
            global stego;
            global text;
            global text_default;
            origin_image=image;
            % Before Embedding process check if origin_image variable is empty
            if (isempty(origin_image))
                uiwait(warndlg('Please Upload an Image From Browse Button'));
            else
                if (app.EnableUploadCheckBox.Value ==  1)
                    text=text_default;
                else
                    % Assign value of TextArea
                    myText=app.TextArea.Value;
                    % Convert text to string
                    text='';
                    data_string=string(myText);
                    % Convert string to array of characters
                    for i=1:length(data_string)
                        text=text+data_string(i)+' ';
                    end
                    text=char(text);
                end
                
                % waiting dialog 2
                % Perform calculations
                % ...
                d.Value = .33;
                d.Message = 'Loading your data';
                pause(1)
                
                % Assign value of Bit
                bit=1;
                if(strcmp(app.SelectBitDropDown.Value,'1 Bit'))
                    bit=1;
                elseif(strcmp(app.SelectBitDropDown.Value,'2 Bits'))
                    bit=2;
                elseif(strcmp(app.SelectBitDropDown.Value,'3 Bits'))
                    bit=3;
                elseif(strcmp(app.SelectBitDropDown.Value,'4 Bits'))
                    bit=4;
                elseif(strcmp(app.SelectBitDropDown.Value,'5 Bits'))
                    bit=5;
                elseif(strcmp(app.SelectBitDropDown.Value,'6 Bits'))
                    bit=6;
                elseif(strcmp(app.SelectBitDropDown.Value,'7 Bits'))
                    bit=7;
                elseif(strcmp(app.SelectBitDropDown.Value,'8 Bits'))
                    bit=8;
                end
                
                % waiting dialog 3
                % Perform calculations
                % ...
                d.Value = .67;
                d.Message = 'Processing the data';
                pause(1)
                
                % Assign value of Method
                stego_image=stego;
                if(strcmp(app.ChooseMethodDropDown.Value,'Sequentional Selected LSB'))
                    % Check length of the secret message
                    required_text_length=required_text_length_Sequential_Selected_LSB(origin_image);
                    if(length(text) > required_text_length)
                        uiwait(errordlg('Length of message is too big'));
                    else
                        stego_image=hide_info_using_Sequential_Selected_LSB(text,origin_image,bit);
                        stego_image=uint8(stego_image);
                        imshow(stego_image,"Parent",app.UIAxes_2);
%                         disp(extract_info_using_Sequential_Selected_LSB(stego_image,bit));
                    end
                    % ======================================================================

                elseif(strcmp(app.ChooseMethodDropDown.Value,'Sequentional Several LSBs'))
                    % Check length of the secret message
                    required_text_length=required_text_length_Sequential_Several_LSBs(origin_image, bit);
                    if(length(text) > required_text_length)
                        uiwait(errordlg('Length of message is too big'));
                    else
                        stego_image=hide_info_using_Sequential_Several_LSBs(text, origin_image, bit);
                        stego_image=uint8(stego_image);
                        imshow(stego_image,"Parent",app.UIAxes_2);
%                         disp(extract_info_using_Sequential_Several_LSBs(stego_image,bit));
                    end
                    % ======================================================================

                elseif(strcmp(app.ChooseMethodDropDown.Value,'Random Selected LSB'))
                    % Check length of the secret message
                    required_text_length=required_text_length_Random_Selected_LSB(origin_image);
                    if(length(text) > required_text_length)
                        uiwait(errordlg('Length of message is too big'));
                    else
                        stego_key=app.StegokeyEditField.Value;
                        stego_image=hide_info_using_Random_Selected_LSB(text,origin_image,bit,stego_key);
                        stego_image=uint8(stego_image);
                        imshow(stego_image,"Parent",app.UIAxes_2);
%                         disp(extract_info_using_Random_Selected_LSB(stego_image,bit,stego_key));
                    end
                    % ======================================================================
                    
                elseif(strcmp(app.ChooseMethodDropDown.Value,'Random Several LSBs'))
                    % Check length of the secret message
                    required_text_length=required_text_length_Random_Several_LSBs(origin_image, bit);
                    if(length(text) > required_text_length)
                        uiwait(errordlg('Length of message is too big'));
                    else
                        stego_key=app.StegokeyEditField.Value;
                        disp(text);
                        stego_image=hide_info_using_Random_Several_LSBs(text,origin_image,bit,stego_key);
                        stego_image=uint8(stego_image);
                        imshow(stego_image,"Parent",app.UIAxes_2);
%                         disp(extract_info_using_Random_Several_LSBs(stego_image,bit,stego_key));
                    end
                end
                % ======================================================================
                
                if (~(isempty(stego_image)) && length(text) <= required_text_length)
                    % Finding value of Peak Signal to Noise Ratio
                    % Note: psnr() parameters must be uint8
                    PSNR_value=psnr(stego_image, origin_image);
                    % Calculates the mean-squared error (MSE)
                    MSE_value = immse(stego_image, origin_image);

                    % Assign values of PSNR and MSE value
                    app.PSNREditField.Value=PSNR_value;
                    app.MSEEditField.Value=MSE_value;
                    
                    % Range of PSNR (Image Status)
                    if (PSNR_value >= 30)
                        app.ImageStatusLamp.Color='green';
                        app.ImageStatusLampLabel.Text='Good Image';
                    elseif (PSNR_value < 30 && PSNR_value >= 20)
                        app.ImageStatusLamp.Color='yellow';
                        app.ImageStatusLampLabel.Text='Medium Image';
                    else
                        app.ImageStatusLamp.Color='red';
                        app.ImageStatusLampLabel.Text='Weak Image';
                    end
                    
                    % When finish Embedding
                    uiwait(helpdlg('Embedding Process Finished'));
                end
                stego=stego_image;
                
                % waiting dialog 4
                % Finish calculations
                % ...
                d.Value = 1;
                d.Message = 'Finishing';
                pause(1)
    
                % Close dialog box
                try
                    close(d);
                    close(fig);
                catch 
                    uiwait(warndlg('Process has been canceled'));
                end
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            clc;
            global text;
            global default_value;
            text=default_value;
            
            % Clear Text Area and File Name
            app.TextArea.Value='';
            app.TextArea.Editable=true;
            app.FileNameEditField.Value='';
            app.UploadTextFileButton.Enable=false;
            app.EnableUploadCheckBox.Value=0;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            clc;
            global stego;
            stego_image=stego;
            % Before save an image check if stego_image variable is empty

            if (isempty(stego_image))
                uiwait(warndlg('Please Press Embed Button'));
            else
                % Saving stego_image
                try
                    filter = {'*.bmp';'*.png';'*.tif'};
                    [file, path] = uiputfile(filter);
                    imwrite(stego_image, [path, file]);
                    uiwait(helpdlg('Image saved seccesfully'));
                catch
                    uiwait(warndlg('Please write image name'));
                end
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Reset All Values
            clc;
            global image;
            global stego;
            global default_value;
            
            answer = questdlg('Are you sure you want to reset all fields?', ...
            	'Reset Dialog', ...
            	'Yes','No','No');
            if (answer == "Yes")
                cla(app.UIAxes,'reset');
                app.UIAxes.XTick='';
                app.UIAxes.YTick='';
                cla(app.UIAxes_2,'reset');
                app.UIAxes_2.XTick='';
                app.UIAxes_2.YTick='';
                app.TextArea.Value='';
                app.PSNREditField.Value=0;
                app.MSEEditField.Value=0;
                app.StegokeyEditField.Value=0;
                app.ChooseMethodDropDown.Value='Sequentional Selected LSB';
                app.SelectBitDropDown.Value='1 Bit';
                app.FileNameEditField.Value='';
                app.EnableUploadCheckBox.Value=0;
                app.UploadTextFileButton.Enable=false;
                app.ImageStatusLamp.Color=[0.50 0.50 0.50];
                app.StegokeyEditField.Editable=false;
                
                % Reset global variable (image)
                image=default_value;
                stego=default_value;
            end
        end

        % Value changed function: EnableUploadCheckBox
        function EnableUploadCheckBoxValueChanged(app, event)
            clc;
            % Reset global variables
            global text;
            global default_value;
            text=default_value;
            % Determine the way to input the secret message 
            value = app.EnableUploadCheckBox.Value;
            if(value == 1)
                app.TextArea.Editable=false;
                app.UploadTextFileButton.Enable=true;
            else
                app.TextArea.Editable=true;
                app.UploadTextFileButton.Enable=false;
                app.FileNameEditField.Value='';
            end
        end

        % Button pushed function: UploadTextFileButton
        function UploadTextFileButtonPushed(app, event)
            clc;
            global text;
            global text_default;
            % Read a text file
            [filename, pathname]=uigetfile({'*.txt'},"open file");
            fullpathname = strcat(pathname,filename);
            
            try
                file_id = fopen(fullpathname,'r');
                data=textscan(file_id,'%s','delimiter','\n');
                fclose(file_id);
                text='';
                data_string=string(data{:});
                % Convert string to array of characters
                for i=1:length(data_string)
                    text=text+data_string(i)+' ';
                end
                text=char(text);
                text_default=text;

                % assign path to the FileNameEditField
                app.FileNameEditField.Value=fullpathname;
                
                % Display uploaded message in TextArea
                app.TextArea.Value=text;
            catch 
                uiwait(warndlg('Please Select TXT File'));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StegoAppUIFigure = uifigure('Visible', 'off');
            app.StegoAppUIFigure.Name = 'Stego App';
            app.StegoAppUIFigure.Icon = 'splash.png';
            app.UIAxes = uiaxes(app.StegoAppUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.StegoAppUIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.SteganographyEmbeddingLabel = uilabel(app.StegoAppUIFigure);
            app.SteganographyEmbeddingLabel.Text = 'Steganography : Embedding ';
            app.Image = uiimage(app.StegoAppUIFigure);
            app.Image.ImageSource = '512x512bb.jpg';
            app.BrowseButton = uibutton(app.StegoAppUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.TextArea = uitextarea(app.StegoAppUIFigure);
            app.TextArea.Placeholder = 'Write a Secret Message .........!  If Not Upload Text File ';
            app.OriginalImageLabel = uilabel(app.StegoAppUIFigure);
            app.OriginalImageLabel.Text = 'Original Image';
            app.StegoImageLabel = uilabel(app.StegoAppUIFigure);
            app.StegoImageLabel.Text = 'Stego Image';
            app.ClearButton = uibutton(app.StegoAppUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.BackButton = uibutton(app.StegoAppUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Icon = 'arrow.png';
            app.BackButton.Text = 'Back';
            app.EmbedButton = uibutton(app.StegoAppUIFigure, 'push');
            app.EmbedButton.ButtonPushedFcn = createCallbackFcn(app, @EmbedButtonPushed, true);
            app.EmbedButton.Text = 'Embed';
            app.SaveButton = uibutton(app.StegoAppUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.StegokeyEditFieldLabel = uilabel(app.StegoAppUIFigure);
            app.StegokeyEditFieldLabel.Text = 'Stego key: ';
            app.StegokeyEditField = uieditfield(app.StegoAppUIFigure, 'numeric');
            app.StegokeyEditField.Editable = 'off';
            app.UploadTextFileButton = uibutton(app.StegoAppUIFigure, 'push');
            app.UploadTextFileButton.ButtonPushedFcn = createCallbackFcn(app, @UploadTextFileButtonPushed, true);
            app.UploadTextFileButton.Enable = 'off';
            app.UploadTextFileButton.Text = 'Upload Text File';
            app.FileNameEditField = uieditfield(app.StegoAppUIFigure, 'text');
            app.FileNameEditField.Editable = 'off';
            app.PSNRLabel = uilabel(app.StegoAppUIFigure);
            app.PSNRLabel.Text = 'PSNR:    ';
            app.PSNREditField = uieditfield(app.StegoAppUIFigure, 'numeric');
            app.PSNREditField.Editable = 'off';
            app.MSEEditFieldLabel = uilabel(app.StegoAppUIFigure);
            app.MSEEditFieldLabel.Text = 'MSE:      ';
            app.MSEEditField = uieditfield(app.StegoAppUIFigure, 'numeric');
            app.MSEEditField.Editable = 'off';
            app.ChooseMethodDropDownLabel = uilabel(app.StegoAppUIFigure);
            app.ChooseMethodDropDownLabel.Text = 'Choose Method: ';
            app.ChooseMethodDropDown = uidropdown(app.StegoAppUIFigure);
            app.ChooseMethodDropDown.Items = {'Sequentional Selected LSB', 'Sequentional Several LSBs', 'Random Selected LSB', 'Random Several LSBs'};
            app.ChooseMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseMethodDropDownValueChanged, true);
            app.ChooseMethodDropDown.Value = 'Sequentional Selected LSB';
            app.SelectBitDropDownLabel = uilabel(app.StegoAppUIFigure);
            app.SelectBitDropDownLabel.Text = 'Select Bit: ';
            app.SelectBitDropDown = uidropdown(app.StegoAppUIFigure);
            app.SelectBitDropDown.Items = {'1 Bit', '2 Bits', '3 Bits', '4 Bits', '5 Bits', '6 Bits', '7 Bits', '8 Bits'};
            app.SelectBitDropDown.Value = '1 Bit';
            app.WritedownyoursecretmessageLabel = uilabel(app.StegoAppUIFigure);
            app.WritedownyoursecretmessageLabel.Text = 'Write down your secret message:     ';
            app.ResetButton = uibutton(app.StegoAppUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.EnableUploadCheckBox = uicheckbox(app.StegoAppUIFigure);
            app.EnableUploadCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableUploadCheckBoxValueChanged, true);
            app.EnableUploadCheckBox.Text = 'Enable Upload ';
            app.ImageStatusLampLabel = uilabel(app.StegoAppUIFigure);
            app.ImageStatusLampLabel.Text = 'Image Status:';
            app.ImageStatusLamp = uilamp(app.StegoAppUIFigure);
            app.CopyrightLabel = uilabel(app.StegoAppUIFigure);
            app.CopyrightLabel.Text = 'Copyright © 2022 by (H.A.I.) group of 4th year CS & IT students supervised by Assist. Prof. Dr. Rasper';
            % Show the figure after all components are created
            app.StegoAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = embedding_screen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StegoAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StegoAppUIFigure)
        end
    end
end