classdef extract < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StegoAppUIFigure              matlab.ui.Figure
        ClearButton                   matlab.ui.control.Button
        AfterextractingyoursecretmessageLabel  matlab.ui.control.Label
        CopyrightLabel                matlab.ui.control.Label
        StegokeyEditField             matlab.ui.control.NumericEditField
        StegokeyEditFieldLabel        matlab.ui.control.Label
        ResetButton                   matlab.ui.control.Button
        SelectBitDropDown             matlab.ui.control.DropDown
        SelectBitDropDownLabel        matlab.ui.control.Label
        ChoseMethodDropDown           matlab.ui.control.DropDown
        ChoseMethodDropDownLabel      matlab.ui.control.Label
        SaveButton                    matlab.ui.control.Button
        ExtractButton                 matlab.ui.control.Button
        BrowseButton                  matlab.ui.control.Button
        BackButton                    matlab.ui.control.Button
        StegoImageLabel               matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        Image                         matlab.ui.control.Image
        SteganographyExtractingLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Center GUI design
            clc;
            app.StegoAppUIFigure.Visible = 'off';
            movegui(app.StegoAppUIFigure,"center")
            app.StegoAppUIFigure.Visible = 'on';
            
            global stego;
            global default_value;
            global text;

            % Reset global variable (image)
            stego=default_value;
            text=default_value;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            clc;
            % Browse a file (image)

            global stego;
            [filename, pathname]=uigetfile({'*.bmp';'*.png';'*.tif'},"open file");
            fullpathname = strcat(pathname,filename);
            try
                stego = imread(fullpathname);
                imshow(stego,"Parent",app.UIAxes);

                % keep window focused
                app.StegoAppUIFigure.Visible='on';
                app.StegoAppUIFigure.Visible='on';
            catch
                uiwait(warndlg('Please select an image'));
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            clc;
            screen1;
            closereq;
        end

        % Value changed function: ChoseMethodDropDown
        function ChoseMethodDropDownValueChanged(app, event)
            clc;
            % Enable or Disable Stegokey Field
            value = app.ChoseMethodDropDown.Value;
            if(strcmp(value, 'Random Selected LSB') || strcmp(value,'Random Several LSBs'))
                app.StegokeyEditField.Editable=true;
            else
                app.StegokeyEditField.Editable=false;
            end
        end

        % Button pushed function: ExtractButton
        function ExtractButtonPushed(app, event)
            % Extract the secret message
            clc;
            % waiting dialog 1
            fig = uifigure;
            d = uiprogressdlg(fig,'Title','Please Wait',...
                'Message','Opening the application');
            pause(.5)

            global stego;
            global text;
            stego_image=stego;

            % Before Extracting process check if stego_image variable is empty
            if (isempty(stego_image))
                uiwait(warndlg('Please Upload an Image From Browse Button'));
            else
                % waiting dialog 2
                % Perform calculations
                % ...
                d.Value = .33;
                d.Message = 'Loading your data';
                pause(1)

                % Assign value of Bit
                bit=1;
                if(strcmp(app.SelectBitDropDown.Value,'1 Bit'))
                    bit=1;
                elseif(strcmp(app.SelectBitDropDown.Value,'2 Bits'))
                    bit=2;
                elseif(strcmp(app.SelectBitDropDown.Value,'3 Bits'))
                    bit=3;
                elseif(strcmp(app.SelectBitDropDown.Value,'4 Bits'))
                    bit=4;
                elseif(strcmp(app.SelectBitDropDown.Value,'5 Bits'))
                    bit=5;
                elseif(strcmp(app.SelectBitDropDown.Value,'6 Bits'))
                    bit=6;
                elseif(strcmp(app.SelectBitDropDown.Value,'7 Bits'))
                    bit=7;
                elseif(strcmp(app.SelectBitDropDown.Value,'8 Bits'))
                    bit=8;
                end

                % waiting dialog 3
                % Perform calculations
                % ...
                d.Value = .67;
                d.Message = 'Processing the data';
                pause(1)

                % Assign value of Method
                if(strcmp(app.ChoseMethodDropDown.Value,'Sequentional Selected LSB'))
                    stego_image=uint8(stego_image);
                    text=extract_info_using_Sequential_Selected_LSB(stego_image,bit);
                    % ======================================================================

                elseif(strcmp(app.ChoseMethodDropDown.Value,'Sequentional Several LSBs'))
                    stego_image=uint8(stego_image);
                    text=extract_info_using_Sequential_Several_LSBs(stego_image,bit);
                    % ======================================================================

                elseif(strcmp(app.ChoseMethodDropDown.Value,'Random Selected LSB'))
                    stego_key=app.StegokeyEditField.Value;
                    stego_image=uint8(stego_image);
                    text=extract_info_using_Random_Selected_LSB(stego_image,bit,stego_key);
                    % ======================================================================

                elseif(strcmp(app.ChoseMethodDropDown.Value,'Random Several LSBs'))
                    stego_key=app.StegokeyEditField.Value;
                    stego_image=uint8(stego_image);
                    text=extract_info_using_Random_Several_LSBs(stego_image,bit,stego_key);
                end
                % ======================================================================
                
                % Assign value into TextArea
                app.TextArea.Value=text;
                
                % When finish Embedding
                uiwait(helpdlg('Extracting Process Finished'));

                % waiting dialog 4
                % Finish calculations
                % ...
                d.Value = 1;
                d.Message = 'Finishing';
                pause(1)

                % Close dialog box
                try
                    close(d);
                    close(fig);
                catch
                    uiwait(warndlg('Process has been canceled'));
                end
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            clc;
            global text;
            global default_value;
            text=default_value;
            
            % Clear Text Area and File Name
            app.TextArea.Value='';
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Reset All Values
            clc;
            global stego;
            global text;
            global default_value;
            
            answer = questdlg('Are you sure you want to reset all fields?', ...
            	'Reset Dialog', ...
            	'Yes','No','No');
            if (answer == "Yes")
                cla(app.UIAxes,'reset');
                app.UIAxes.XTick='';
                app.UIAxes.YTick='';
                
                app.TextArea.Value='';
                app.StegokeyEditField.Value=0;
                app.ChoseMethodDropDown.Value='Sequentional Selected LSB';
                app.SelectBitDropDown.Value='1 Bit';
                app.StegokeyEditField.Editable=false;
                
                % Reset global variable (image)
                stego=default_value;
                text=default_value;
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            clc;
            global text;
            mytext=text;
            % Before save a secret message check if mytext variable is empty

            if (isempty(mytext))
                uiwait(warndlg('Please Press Extract Button'));
            else
                % Saving secret message
                try
                    % adding newlines to the secret message
                    my_message='';
                    index=1;
                    count=0;
                    for i=1:length(mytext)
                        if (mytext(i)==' ')
                            count=count+1;
                            if(count==20)
                                my_message(index)=newline;
                                index=index+1;
                                count=0;
                                continue;
                            end
                        end
                        my_message(index)=mytext(i);
                        index=index+1;
                    end
                    
                    filter = {'*.txt'};
                    [file, path] = uiputfile(filter);
                    
                    textfile =  fopen([path, file], 'w');
                    fwrite(textfile, my_message, 'char');
                    fclose(textfile);
                    uiwait(helpdlg('Secret Message saved seccesfully'));
                catch
                    uiwait(warndlg('Please Write Text File Name'));
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StegoAppUIFigure = uifigure('Visible', 'off');
            app.StegoAppUIFigure.Name = 'Stego App';
            app.StegoAppUIFigure.Icon = 'splash.png';
            app.UIAxes = uiaxes(app.StegoAppUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.SteganographyExtractingLabel = uilabel(app.StegoAppUIFigure);
            app.SteganographyExtractingLabel.Text = 'Steganography : Extracting ';
            app.Image = uiimage(app.StegoAppUIFigure);
            app.Image.ImageSource = '512x512bb.jpg';
            app.TextArea = uitextarea(app.StegoAppUIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Placeholder = 'Extract Your Secret Message .........! ';
            app.StegoImageLabel = uilabel(app.StegoAppUIFigure);
            app.StegoImageLabel.Text = 'Stego Image';
            app.BackButton = uibutton(app.StegoAppUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Icon = 'arrow.png';
            app.BackButton.Text = 'Back';
            app.BrowseButton = uibutton(app.StegoAppUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ExtractButton = uibutton(app.StegoAppUIFigure, 'push');
            app.ExtractButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractButtonPushed, true);
            app.ExtractButton.Text = 'Extract';
            app.SaveButton = uibutton(app.StegoAppUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ChoseMethodDropDownLabel = uilabel(app.StegoAppUIFigure);
            app.ChoseMethodDropDownLabel.Text = 'Chose Method: ';
            app.ChoseMethodDropDown = uidropdown(app.StegoAppUIFigure);
            app.ChoseMethodDropDown.Items = {'Sequentional Selected LSB', 'Sequentional Several LSBs', 'Random Selected LSB', 'Random Several LSBs'};
            app.ChoseMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoseMethodDropDownValueChanged, true);
            app.ChoseMethodDropDown.Value = 'Sequentional Selected LSB';
            app.SelectBitDropDownLabel = uilabel(app.StegoAppUIFigure);
            app.SelectBitDropDownLabel.Text = 'Select Bit: ';
            app.SelectBitDropDown = uidropdown(app.StegoAppUIFigure);
            app.SelectBitDropDown.Items = {'1 Bit', '2 Bits', '3 Bits', '4 Bits', '5 Bits', '6 Bits', '7 Bits', '8 Bits'};
            app.SelectBitDropDown.Value = '1 Bit';
            app.ResetButton = uibutton(app.StegoAppUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.StegokeyEditFieldLabel = uilabel(app.StegoAppUIFigure);
            app.StegokeyEditFieldLabel.Text = 'Stego key: ';
            app.StegokeyEditField = uieditfield(app.StegoAppUIFigure, 'numeric');
            app.StegokeyEditField.Editable = 'off';
            app.CopyrightLabel = uilabel(app.StegoAppUIFigure);
            app.CopyrightLabel.Text = 'Copyright © 2022 by (H.A.I.) group of 4th year CS & IT students supervised by Assist. Prof. Dr. Rasper';
            app.AfterextractingyoursecretmessageLabel = uilabel(app.StegoAppUIFigure);
            app.AfterextractingyoursecretmessageLabel.Text = 'After extracting your secret message:     ';
            app.ClearButton = uibutton(app.StegoAppUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.StegoAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = extract

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StegoAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StegoAppUIFigure)
        end
    end
end