classdef screen1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StegoAppUIFigure             matlab.ui.Figure
        ImageSteganographyPanel      matlab.ui.container.Panel
        LogOutButton                 matlab.ui.control.Button
        CopyrightLabel               matlab.ui.control.Label
        Image2                       matlab.ui.control.Image
        WelcomeToSteganographyPanel  matlab.ui.container.Panel
        AboutUS                      matlab.ui.control.Button
        ExtractingButton             matlab.ui.control.Button
        EmbedingButton               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Center GUI design
            clc;
            app.StegoAppUIFigure.Visible = 'off';
            movegui(app.StegoAppUIFigure,"center")
            app.StegoAppUIFigure.Visible = 'on';
        end

        % Button pushed function: EmbedingButton
        function EmbedingButtonPushed(app, event)
            clc;
            embedding_screen;
            closereq;
        end

        % Button pushed function: ExtractingButton
        function ExtractingButtonPushed(app, event)
            clc;
            extract;
            closereq;
        end

        % Button pushed function: LogOutButton
        function LogOutButtonPushed(app, event)
            clc;
            login_form;
            closereq;
        end

        % Button pushed function: AboutUS
        function AboutUSButtonPushed(app, event)
            clc;
            about_us;
            closereq;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StegoAppUIFigure = uifigure('Visible', 'off');
            app.StegoAppUIFigure.Name = 'Stego App';
            app.StegoAppUIFigure.Icon = 'splash.png';
            app.ImageSteganographyPanel = uipanel(app.StegoAppUIFigure);
            app.ImageSteganographyPanel.Title = 'Image Steganography ';
            app.WelcomeToSteganographyPanel = uipanel(app.ImageSteganographyPanel);
            app.WelcomeToSteganographyPanel.Title = 'Welcome To Steganography!';
            app.EmbedingButton = uibutton(app.WelcomeToSteganographyPanel, 'push');
            app.EmbedingButton.ButtonPushedFcn = createCallbackFcn(app, @EmbedingButtonPushed, true);
            app.EmbedingButton.Text = 'Embeding';
            app.ExtractingButton = uibutton(app.WelcomeToSteganographyPanel, 'push');
            app.ExtractingButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractingButtonPushed, true);
            app.ExtractingButton.Text = 'Extracting';
            app.AboutUS = uibutton(app.WelcomeToSteganographyPanel, 'push');
            app.AboutUS.ButtonPushedFcn = createCallbackFcn(app, @AboutUSButtonPushed, true);
            app.AboutUS.Text = 'About Us';
            app.Image2 = uiimage(app.ImageSteganographyPanel);
            app.Image2.ImageSource = '512x512bb.jpg';
            app.CopyrightLabel = uilabel(app.ImageSteganographyPanel);
            app.CopyrightLabel.Text = 'Copyright © 2022 by (H.A.I.) group of 4th year CS & IT students supervised by Assist. Prof. Dr. Rasper';
            app.LogOutButton = uibutton(app.ImageSteganographyPanel, 'push');
            app.LogOutButton.ButtonPushedFcn = createCallbackFcn(app, @LogOutButtonPushed, true);
            app.LogOutButton.Text = 'Log Out';
            % Show the figure after all components are created
            app.StegoAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = screen1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StegoAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StegoAppUIFigure)
        end
    end
end