classdef sign_up_form < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StegoAppUIFigure         matlab.ui.Figure
        ImageSteganographyPanel  matlab.ui.container.Panel
        CopyrightLabel           matlab.ui.control.Label
        BackButton               matlab.ui.control.Button
        Image2                   matlab.ui.control.Image
        SignUpPanel              matlab.ui.container.Panel
        PasswordHTML_2           matlab.ui.control.HTML
        PasswordHTML             matlab.ui.control.HTML
        Image_2                  matlab.ui.control.Image
        Image                    matlab.ui.control.Image
        SignUpButton             matlab.ui.control.Button
        EditField                matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Center GUI design
            clc;
            app.StegoAppUIFigure.Visible = 'off';
            movegui(app.StegoAppUIFigure,"center")
            app.StegoAppUIFigure.Visible = 'on';
        end

        % Button pushed function: SignUpButton
        function SignUpButtonPushed(app, event)
            clc;
            % Read value from username, password and confirm password fields
            user=app.EditField.Value;
            pass= app.PasswordHTML.Data;
            conf_pass= app.PasswordHTML_2.Data;

            % Check if username, password and confirm password fields are empty
            if (user == "" || pass == "" || conf_pass == "")
                uiwait(warndlg('Fields Must Not Be Empty'));
            elseif (length(pass) ~= length(conf_pass))
                uiwait(warndlg('Password Does not Match'));
            elseif (strcmp(pass,conf_pass) == 0)
                uiwait(warndlg('Password Does not Match'));
            else
                % Checking if folder and excel file exist
                folder = strcat(getenv('UserProfile'), "\Desktop\Image_Stego");
                databaseFileName = 'login_database.xlsx';
                filename = fullfile(folder, databaseFileName);
                if ~exist(folder, 'dir')
                    mkdir(folder);
                    xlswrite(filename,' ');
                    
                    % Assign default values
                    new_table = table();
                    new_table.Username={'hozan'; 'ismail'; 'andesha'};
                    new_table.Password={'a1234'; 'a4321'; 'abc123'};
                    writetable(new_table,filename,'Sheet',1,'Range','A1');
                elseif ~exist(filename, 'file')
                    xlswrite(filename,' ');
                    
                    % Assign default values
                    new_table = table();
                    new_table.Username={'hozan'; 'ismail'; 'andesha'};
                    new_table.Password={'a1234'; 'a4321'; 'abc123'};
                    writetable(new_table,filename,'Sheet',1,'Range','A1');
                end

                % Read data from excel file
                my_table = readtable(filename,'Sheet',1,'Range','A1');
                [row, ~] = size(my_table);
                flage = 0;

                % check if username and password exist
                for i=1:row
                    if ( isequal(my_table.Username{i},user) && isequal(my_table.Password{i}, pass ))
                        flage = 1;
                        break;
                    end
                end

                if (flage == 1)
                    uiwait(warndlg('Username Exist'));
                else
                    new_table = table();
                    new_table.Username=user;
                    new_table.Password=pass;
                    table_add=[my_table; new_table];
                    writetable(table_add,filename,'Sheet',1,'Range','A1');
                    uiwait(helpdlg('Successfully Sign UP!'));

                    % Reset fields and assign placeholders
                    app.EditField.Value='';
                    app.PasswordHTML.Data='';
                    app.PasswordHTML_2.Data='';
                end
            end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)

        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            clc;
            login_form;
            closereq;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StegoAppUIFigure = uifigure('Visible', 'off');
            app.StegoAppUIFigure.Name = 'Stego App';
            app.StegoAppUIFigure.Icon = 'splash.png';
            app.ImageSteganographyPanel = uipanel(app.StegoAppUIFigure);
            app.ImageSteganographyPanel.Title = 'Image Steganography ';
            app.SignUpPanel = uipanel(app.ImageSteganographyPanel);
            app.SignUpPanel.Title = 'Sign Up ';
            app.EditField = uieditfield(app.SignUpPanel, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Tooltip = {''};
            app.EditField.Placeholder = 'username';
            app.SignUpButton = uibutton(app.SignUpPanel, 'push');
            app.SignUpButton.ButtonPushedFcn = createCallbackFcn(app, @SignUpButtonPushed, true);
            app.SignUpButton.Text = 'Sign Up';
            app.Image = uiimage(app.SignUpPanel);
            app.Image.ImageSource = 'icons8-user-64.png';
            app.Image_2 = uiimage(app.SignUpPanel);
            app.Image_2.ImageSource = 'icons8-lock-50.png';
            app.PasswordHTML = uihtml(app.SignUpPanel);
            app.PasswordHTML.HTMLSource = 'password.html';
            app.PasswordHTML_2 = uihtml(app.SignUpPanel);
            app.PasswordHTML_2.HTMLSource = 'conf_password.html';
            app.Image2 = uiimage(app.ImageSteganographyPanel);
            app.Image2.ImageSource = '512x512bb.jpg';
            app.BackButton = uibutton(app.ImageSteganographyPanel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Icon = 'arrow.png';
            app.BackButton.Text = 'Back';
            app.CopyrightLabel = uilabel(app.ImageSteganographyPanel);
            app.CopyrightLabel.Text = 'Copyright © 2022 by (H.A.I.) group of 4th year CS & IT students supervised by Assist. Prof. Dr. Rasper';
            % Show the figure after all components are created
            app.StegoAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sign_up_form

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StegoAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StegoAppUIFigure)
        end
    end
end